classdef HalfCarResponse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Occursatt4sLabel            matlab.ui.control.Label
        Occursatt1sLabel            matlab.ui.control.Label
        BumpHeightmSlider           matlab.ui.control.Slider
        BumpHeightmSliderLabel      matlab.ui.control.Label
        Accelerationms2Slider       matlab.ui.control.Slider
        Accelerationms2SliderLabel  matlab.ui.control.Label
        DampingcrNsmEditField       matlab.ui.control.NumericEditField
        DampingcrNsmEditFieldLabel  matlab.ui.control.Label
        SpringkrNmEditField         matlab.ui.control.NumericEditField
        SpringkrNmEditFieldLabel    matlab.ui.control.Label
        ExitButton                  matlab.ui.control.Button
        RunSimButton                matlab.ui.control.Button
        VehicleParametersLabel      matlab.ui.control.Label
        DampingcfNsmEditField       matlab.ui.control.NumericEditField
        DampingcfNsmEditFieldLabel  matlab.ui.control.Label
        SpringkfNmEditField         matlab.ui.control.NumericEditField
        SpringkfNmEditFieldLabel    matlab.ui.control.Label
        MasskgEditField             matlab.ui.control.NumericEditField
        MasskgEditFieldLabel        matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'x0', [-4.335788328729104e-018;-1.201224489795918e-001;6.462348535570529e-027;-1.033975765691285e-025]); %initial condition

            assignin('base', 'Lf', 0.9) % front hub displacement from body CG
            assignin('base', 'Lr', 1.2) % rear hub displacement from body CG
            assignin('base', 'Iyy', 2100) % body moment of inertia about y-axis in kgm^2
        end

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)
            %Update parameters
            assignin('base', 'Mb', app.MasskgEditField.Value) % body mass in kg
            assignin('base', 'kf', app.SpringkfNmEditField.Value) % front suspension stiffness in N/m
            assignin('base', 'kr', app.SpringkrNmEditField.Value) % rear suspension stiffness in N/m
            assignin('base', 'cf', app.DampingcfNsmEditField.Value) % front suspension damping in N/(m/s)
            assignin('base', 'cr', app.DampingcrNsmEditField.Value) % rear suspension damping in N/(m/s)
            assignin('base', 'accelFinal', app.Accelerationms2Slider.Value) 
            assignin('base', 'bumpFinal', app.BumpHeightmSlider.Value) 

            %Run simulink simulation
            evalc('sim(''sldemo_suspn'')'); 
            close_system('sldemo_suspn',0);

            %Retrieve output
            Time = sldemo_suspn_output.get('Z').Values.Time;
            Z = sldemo_suspn_output.get('Z').Values.Data;
            Zdot = sldemo_suspn_output.get('Zdot').Values.Data;
            Theta = sldemo_suspn_output.get('Theta').Values.Data;
            RoadHeightf = sldemo_suspn_output.get('h').Values.Data;
            RoadHeightr = sldemo_suspn_output.get('h1').Values.Data;
   
            n = 0;
            t = 0;
            while n < 8 %Runs for 7 seconds
                n = n + 0.01;
                t = t + 1;
                
                cla(app.UIAxes) %Clear Screen

                %Draw the car
                r = rectangle(app.UIAxes,'Position', [Time(t)-1.4,0.3-Z(t),1.5,0.2]); %Body
                g = hgtransform(app.UIAxes,'Matrix', makehgtform('translate',[1.75 0 0],'zrotate', Theta(t),'translate',[-1.75 0 0])); %Rotation in deg
                r.Parent = g;
                rectangle(app.UIAxes,'Position', [Time(t),0+RoadHeightf(t),0.1,0.1],'Curvature',[1,1]); %Front Wheel
                rectangle(app.UIAxes,'Position', [Time(t)-1.4,0+RoadHeightr(t),0.1,0.1],'Curvature',[1,1]); %Rear Wheel
                     
    
                %Plot road,work in progress
                hold(app.UIAxes,"on")
                plot(app.UIAxes,Time,RoadHeightf,'-');
                
                    
                %Plot body acceleration graph
                plot(app.UIAxes2,Time,Zdot,'b');

                pause(0.01);
            end
        end

        % Value changed function: MasskgEditField
        function MasskgEditFieldValueChanged(app, event)

        end

        % Value changed function: SpringkfNmEditField
        function SpringkfNmEditFieldValueChanged(app, event)

        end

        % Value changed function: DampingcfNsmEditField
        function DampingcfNsmEditFieldValueChanged(app, event)

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Half-Vehicle Response')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 7];
            app.UIAxes.YLim = [-0.1 2];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Body Acceleration')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.ValueChangedFcn = createCallbackFcn(app, @MasskgEditFieldValueChanged, true);
            app.MasskgEditField.Placeholder = '800';
            app.MasskgEditField.Value = 1200;
            app.SpringkfNmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringkfNmEditFieldLabel.Text = 'Spring kf (N/m)';
            app.SpringkfNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringkfNmEditField.ValueChangedFcn = createCallbackFcn(app, @SpringkfNmEditFieldValueChanged, true);
            app.SpringkfNmEditField.Placeholder = '28000';
            app.SpringkfNmEditField.Value = 28000;
            app.DampingcfNsmEditFieldLabel = uilabel(app.UIFigure);
            app.DampingcfNsmEditFieldLabel.Text = 'Damping cf (N*s/m)';
            app.DampingcfNsmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DampingcfNsmEditField.ValueChangedFcn = createCallbackFcn(app, @DampingcfNsmEditFieldValueChanged, true);
            app.DampingcfNsmEditField.Placeholder = '2500';
            app.DampingcfNsmEditField.Value = 2500;
            app.VehicleParametersLabel = uilabel(app.UIFigure);
            app.VehicleParametersLabel.Text = 'Vehicle Parameters:';
            app.RunSimButton = uibutton(app.UIFigure, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'Run Sim';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SpringkrNmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringkrNmEditFieldLabel.Text = 'Spring kr (N/m)';
            app.SpringkrNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringkrNmEditField.Placeholder = '21000';
            app.SpringkrNmEditField.Value = 21000;
            app.DampingcrNsmEditFieldLabel = uilabel(app.UIFigure);
            app.DampingcrNsmEditFieldLabel.Text = 'Damping cr (N*s/m)';
            app.DampingcrNsmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DampingcrNsmEditField.Placeholder = '2000';
            app.DampingcrNsmEditField.Value = 2000;
            app.Accelerationms2SliderLabel = uilabel(app.UIFigure);
            app.Accelerationms2SliderLabel.Text = 'Acceleration (m/s^2)';
            app.Accelerationms2Slider = uislider(app.UIFigure);
            app.Accelerationms2Slider.Limits = [-10 10];
            app.BumpHeightmSliderLabel = uilabel(app.UIFigure);
            app.BumpHeightmSliderLabel.Text = 'Bump Height (m)';
            app.BumpHeightmSlider = uislider(app.UIFigure);
            app.BumpHeightmSlider.Limits = [-0.1 0.1];
            app.Occursatt1sLabel = uilabel(app.UIFigure);
            app.Occursatt1sLabel.Text = '(Occurs at t=1s)';
            app.Occursatt4sLabel = uilabel(app.UIFigure);
            app.Occursatt4sLabel.Text = '(Occurs at t=4s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HalfCarResponse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end